classdef AutoCalibrator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        CalibrationParametersPanel  matlab.ui.container.Panel
        GridLayout2                 matlab.ui.container.GridLayout
        WeightMapPanel              matlab.ui.container.Panel
        WeightMapImage              matlab.ui.control.Image
        CoordinateTransformPanel    matlab.ui.container.Panel
        TransformParamLabel         matlab.ui.control.Label
        ETLCurrentRawValuePanel     matlab.ui.container.Panel
        ETLCurrentLabel             matlab.ui.control.Label
        ParametersPanel             matlab.ui.container.Panel
        GridLayout3                 matlab.ui.container.GridLayout
        CalibrationPatternPanel     matlab.ui.container.Panel
        GridLayout9                 matlab.ui.container.GridLayout
        CalibPatternScaleSpinner    matlab.ui.control.Spinner
        FirstCalibSpotYSpinner      matlab.ui.control.Spinner
        FirstCalibSpotXSpinner      matlab.ui.control.Spinner
        PatternScaleLabel           matlab.ui.control.Label
        FirstSpotYmLabel            matlab.ui.control.Label
        FirstSpotXmLabel            matlab.ui.control.Label
        SavedFolderPanel            matlab.ui.container.Panel
        GridLayout5_2               matlab.ui.container.GridLayout
        FolderSelectButton          matlab.ui.control.Button
        CurrentFolderTextArea       matlab.ui.control.TextArea
        FolderSaveButton            matlab.ui.control.Button
        FolderOpenButton            matlab.ui.control.Button
        SLMPanel                    matlab.ui.container.Panel
        GridLayout5                 matlab.ui.container.GridLayout
        SLMHeightLabel              matlab.ui.control.Label
        SLMWidthLabel               matlab.ui.control.Label
        SLMPixelPitchLabel          matlab.ui.control.Label
        PixelHeightLabel            matlab.ui.control.Label
        PixelWidthLabel             matlab.ui.control.Label
        PixelPitchmLabel            matlab.ui.control.Label
        OpticalSystemPanel          matlab.ui.container.Panel
        GridLayout4_2               matlab.ui.container.GridLayout
        FocalLengthSpinner          matlab.ui.control.Spinner
        FocalLengthoflensmLabel     matlab.ui.control.Label
        LaserPanel                  matlab.ui.container.Panel
        GridLayout4                 matlab.ui.container.GridLayout
        WaveLengthSpinner           matlab.ui.control.Spinner
        WaveLengthnmLabel           matlab.ui.control.Label
        TabGroup                    matlab.ui.container.TabGroup
        AutoTab                     matlab.ui.container.Tab
        GridLayout6                 matlab.ui.container.GridLayout
        GridLayout10                matlab.ui.container.GridLayout
        AutoCalibrationStatusImage  matlab.ui.control.Image
        AutoCalibrationStatusAxes   matlab.ui.control.UIAxes
        GridLayout7                 matlab.ui.container.GridLayout
        GridLayout8                 matlab.ui.container.GridLayout
        StopAutoCalibrationButton   matlab.ui.control.Button
        StartAutoCalibrationButton  matlab.ui.control.Button
        StatusPanel                 matlab.ui.container.Panel
        CalibrationStatusTree       matlab.ui.container.Tree
        ETLScanTab                  matlab.ui.container.Tab
        GridLayout11                matlab.ui.container.GridLayout
        AutoScanPanel               matlab.ui.container.Panel
        AutoScanStartButton         matlab.ui.control.Button
        ManualSelectionPanel        matlab.ui.container.Panel
        GridLayout12                matlab.ui.container.GridLayout
        ManualETLSelectButton       matlab.ui.control.Button
        ManualETLApplyButton        matlab.ui.control.Button
        ManualETLValueSlider        matlab.ui.control.Slider
        AutoETLScanStatusImage      matlab.ui.control.Image
        AutoETLScanStatusUIAxes     matlab.ui.control.UIAxes
        CoordinateCorrectionTab     matlab.ui.container.Tab
        GridLayout13                matlab.ui.container.GridLayout
        AutoTestPanel               matlab.ui.container.Panel
        AutoAffineTestStartButton   matlab.ui.control.Button
        GridLayout15                matlab.ui.container.GridLayout
        ManualPanel                 matlab.ui.container.Panel
        GridLayout16                matlab.ui.container.GridLayout
        ManualAffineTestButton      matlab.ui.control.Button
        YP3EditField                matlab.ui.control.NumericEditField
        YP3EditFieldLabel           matlab.ui.control.Label
        XP3EditField                matlab.ui.control.NumericEditField
        XP3EditFieldLabel           matlab.ui.control.Label
        YP2EditField                matlab.ui.control.NumericEditField
        YP2EditFieldLabel           matlab.ui.control.Label
        XP2EditField                matlab.ui.control.NumericEditField
        XP2EditFieldLabel           matlab.ui.control.Label
        YP1EditField                matlab.ui.control.NumericEditField
        YP1EditFieldLabel           matlab.ui.control.Label
        XP1EditField                matlab.ui.control.NumericEditField
        XP1EditFieldLabel           matlab.ui.control.Label
        AutoAffineTestLabel         matlab.ui.control.Label
        AutoAffineTestImage         matlab.ui.control.Image
        WeightMapTab                matlab.ui.container.Tab
    end

    
    properties (Access = private)
        SLM devices.slm.PhaseSLM = devices.slm.DummyPhaseSLM
        ETL devices.etl.ETL = devices.etl.DummyETL
        Camera devices.camera.Camera = devices.camera.DummyCamera
        Working logical
        CurrentTask = [] %parallel.Future 
        Calibrator = [] %calibration.PositionCalibrator
        ETLValue = []
        WeightMap = [] %calibration.IntensityWeightMap
    end

    methods (Access = public)
        function [etlValueRaw,calibrator,intensityMap] = getResult(app)
            etlValueRaw = app.ETLValue;
            calibrator = app.Calibrator;
            intensityMap = app.WeightMap;
        end
    end
    
    methods (Access = private)
        function success = saveFolder(app,folderpath)
            if exist(folderpath,"dir") ~= 7
                mkdir(folderpath);
            end
            success = true;
            savedData = table;
            savedData.WaveLength=app.WaveLengthSpinner.Value;
            savedData.FocalLength=app.FocalLengthSpinner.Value;
            savedData.SpotX=app.FirstCalibSpotXSpinner.Value;
            savedData.SpotY=app.FirstCalibSpotYSpinner.Value;
            savedData.PatternScale=app.CalibPatternScaleSpinner.Value;
            writetable(savedData,folderpath+"/config.dat")
            if ~isempty(app.ETLValue)
                writematrix(app.ETLValue,folderpath+"/etl_current_raw.csv");
            end
            if ~isempty(app.Calibrator)
                app.Calibrator.saveTo(folderpath+"/pos_calibrator.csv");
            end
            if ~isempty(app.WeightMap)
                app.WeightMap.saveTo(folderpath+"/weight_map.csv");
            end
        end

        function success = openFolder(app,folderpath)
            arguments(Input)
                app tools.gui.AutoCalibrator
                folderpath
            end
            if exist(folderpath,"dir") ~= 7
                success = false;
                return;
            end
            success = true;
            fileconfig=folderpath+"/config.dat";
            if isfile(fileconfig)
                savedData=readtable(fileconfig);
                app.WaveLengthSpinner.Value=savedData.WaveLength;
                app.FocalLengthSpinner.Value=savedData.FocalLength;
                app.FirstCalibSpotXSpinner.Value=savedData.SpotX;
                app.FirstCalibSpotYSpinner.Value=savedData.SpotY;
                app.CalibPatternScaleSpinner.Value=savedData.PatternScale;
            end
            fileetl=folderpath+"/etl_current_raw.csv";
            if isfile(fileetl)
                app.setETLValue(readmatrix(fileetl));
            end
            filecalib=folderpath+"/pos_calibrator.csv";
            if isfile(filecalib)
                app.setCalibrator(calibration.PositionCalibrator.loadFrom(filecalib));
            end
            filewm=folderpath+"/weight_map.csv";
            if isfile(filewm)
                app.setWeightMap(calibration.IntensityWeightMap.loadFrom(filewm));
            end
        end

        function setETLValue(app,value)
            app.ETLValue = value;
            app.ETLCurrentLabel.Text = string(value);
        end
        function setCalibrator(app,calibrator)
            app.Calibrator=calibrator;
            app.TransformParamLabel.Text = calibrator.toSimpleString();
        end
        function setWeightMap(app,weightmap)
            app.WeightMap=weightmap;
            wm = rescale(app.WeightMap.WeightMap);
            viewImg = cat(3,wm,wm,wm);
            app.WeightMapImage.ImageSource = viewImg;
        end
        
        function phasemap = getEmptyPhaseMap(app)
            arguments(Output)
                phasemap devices.slm.PhaseMap
            end
            [ysize,xsize]=app.SLM.getPixelArraySize();
            pixelpitch_um=app.SLM.getPixelPitch();
            phasemap=devices.slm.PhaseMap(xsize,ysize,pixelpitch_um,pixelpitch_um,app.FocalLengthSpinner.Value,app.WaveLengthSpinner.Value);
        end

        % === ETL Scan

        function calibration_etlScan(app,imageComponent,graphComponent,afterFunction)
            arguments(Input)
                app tools.gui.AutoCalibrator
                imageComponent
                graphComponent
                afterFunction = []
            end
            etlscanner = calibration.ETLScanner( ...
                app.Camera, ...
                app.SLM, ...
                app.ETL, ...
                app.FirstCalibSpotXSpinner.Value, ...
                app.FirstCalibSpotYSpinner.Value, ...
                0, ...
                app.FocalLengthSpinner.Value, ...
                app.WaveLengthSpinner.Value ...
            );

            treenode=uitreenode(app.CalibrationStatusTree,"Text","ETL Scan");
            uitreenode(treenode,"Text","Processing ...");
            
            % app.CurrentTask=parfeval(backgroundPool,@(scanner) tools.gui.AutoCalibrator.calibration_etlScan_worker(scanner,q,afterQ),0,etlscanner);
            tools.gui.AutoCalibrator.calibration_etlScan_worker(etlscanner, ...
                @(data) app.calibration_etlScan_updateUI(data{1},data{2},imageComponent,graphComponent), ...
                @(data) app.calibration_etlScan_finish(data,treenode,afterFunction));
        end
        
        function calibration_etlScan_updateUI(app,scores,image,imageComponent,graphComponent)
            if ~isempty(image)
                if ~isempty(imageComponent)
                    filt=fspecial("average",3);
                    viewImg=rescale(imfilter(double(image),filt));
                    imageComponent.ImageSource = cat(3,viewImg,viewImg,viewImg);
                end
                if ~isempty(graphComponent)
                    plot(graphComponent,1:length(scores),scores);
                end
            end
        end

        function calibration_etlScan_finish(app,etlvalue,treenode,afterFunction)
            app.setETLValue(etlvalue);
            app.ETL.setCurrentRaw(app.ETLValue);
            uitreenode(treenode,"Text","Finished : "+string(etlvalue));
            if ~isempty(afterFunction)
                afterFunction();
            end
        end

        % === Affine Test

        function calibration_testAffineTransform(app,imageComponent,afterFunction)
            arguments(Input)
                app tools.gui.AutoCalibrator
                imageComponent
                afterFunction = []
            end
            affineTester = calibration.AffineTransformTester( ...
                app.Camera, ...
                app.SLM, ...
                app.FirstCalibSpotXSpinner.Value, ...
                app.FirstCalibSpotYSpinner.Value, ...
                0, ...
                app.FocalLengthSpinner.Value, ...
                app.WaveLengthSpinner.Value, ...
                app.CalibPatternScaleSpinner.Value ...
            );

            treenode=uitreenode(app.CalibrationStatusTree,"Text","Affine Transformation Test");

            %app.CurrentTask=parfeval(backgroundPool,@() tools.gui.AutoCalibrator.calibration_testAffineTransform_worker(affineTester,q,afterQ),0);
            tools.gui.AutoCalibrator.calibration_testAffineTransform_worker(affineTester, ...
                @(data) app.calibration_testAffineTransform_updateUI(data{1},data{2},data{3},data{4},imageComponent,treenode), ...
                @(data) app.calibration_testAffineTransform_finish(data,treenode,afterFunction));
        end

        function calibration_testAffineTransform_finish(app,calibrator,treenode,afterFunction)
            app.setCalibrator(calibrator);
            uitreenode(treenode,"Text","Finished");
            if ~isempty(afterFunction)
                afterFunction();
            end
        end

        function calibration_testAffineTransform_updateUI(app,calibrator,image,detectedSpots,expectedSpots,imageComponent,treenode)
            if ~isempty(image)
                if ~isempty(imageComponent)
                    viewImg=app.addMarkerToImage(image,detectedSpots,expectedSpots);
                    imageComponent.ImageSource = viewImg;
                end
                if isempty(calibrator)
                    uitreenode(treenode,"Text","Failed to detect enough amount of points");
                else
                    uitreenode(treenode,"Text","Estimating ...");
                end
            end
        end

        function viewImg=addMarkerToImage(~,image,detectedSpots,expectedSpots)
            filt=fspecial("average",3);
            img=rescale(imfilter(double(image),filt));
            viewImg = cat(3,img,img,img);
            height=size(img,1);
            width=size(img,2);
            markRadius=10;
            markWidth=1;
            for i=1:size(detectedSpots,1)
                spotPos=round(detectedSpots(i,:));
                viewImg(max([1,spotPos(2)-markWidth]):min([width,spotPos(2)+markWidth]),max([1,spotPos(1)-markRadius]):min([height,spotPos(1)+markRadius]),1)=1;
                viewImg(max([1,spotPos(2)-markRadius]):min([width,spotPos(2)+markRadius]),max([1,spotPos(1)-markWidth]):min([height,spotPos(1)+markWidth]),1)=1;
            end
            for i=1:size(expectedSpots,1)
                spotPos=round(expectedSpots(i,:));
                viewImg(max([1,spotPos(2)-markWidth]):min([width,spotPos(2)+markWidth]),max([1,spotPos(1)-markRadius]):min([height,spotPos(1)+markRadius]),2)=1;
                viewImg(max([1,spotPos(2)-markRadius]):min([width,spotPos(2)+markRadius]),max([1,spotPos(1)-markWidth]):min([height,spotPos(1)+markWidth]),2)=1;
            end
        end

        % === scan weightmap
        function calibration_scanWeightMap(app,imageComponent,afterFunction)
            arguments(Input)
                app tools.gui.AutoCalibrator
                imageComponent
                afterFunction = []
            end
            if isempty(app.Calibrator)
                return;
            end
            weightmapScanner = calibration.WeightMapScanner( ...
                app.Camera, ...
                app.SLM, ...
                app.Calibrator, ...
                app.FocalLengthSpinner.Value, ...
                app.WaveLengthSpinner.Value ...
            );

            treenode=uitreenode(app.CalibrationStatusTree,"Text","Intensity Map Creation");

            %app.CurrentTask=parfeval(backgroundPool,@() tools.gui.AutoCalibrator.calibration_scanWeightMap_worker(weightmapScanner,q,afterQ),0);
            tools.gui.AutoCalibrator.calibration_scanWeightMap_worker(weightmapScanner, ...
                @(data) app.calibration_scanWeightMap_updateUI(data{1},imageComponent,treenode), ...
                @(data) app.calibration_scanWeightMap_finish(data,treenode,afterFunction));
        end
        function calibration_scanWeightMap_updateUI(app,image,imageComponent,treenode)
            arguments(Input)
                app tools.gui.AutoCalibrator
                image
                imageComponent
                treenode
            end
            if ~isempty(image)
                if ~isempty(imageComponent)
                    filt=fspecial("average",3);
                    viewImg=rescale(imfilter(double(image),filt));
                    imageComponent.ImageSource = cat(3,viewImg,viewImg,viewImg);
                end
            end
        end

        function calibration_scanWeightMap_finish(app,weightmap,treenode,afterFunction)
            arguments
                app tools.gui.AutoCalibrator
                weightmap calibration.IntensityWeightMap
                treenode
                afterFunction
            end

            app.setWeightMap(weightmap);
            uitreenode(treenode,"Text","Finished");
            if ~isempty(afterFunction)
                afterFunction();
            end
        end

        % ===

        function startAutoCalibration(app)
            if ~isempty(app.CurrentTask)
                cancel(app.CurrentTask);
                app.CurrentTask=[];
            end
            app.StartAutoCalibrationButton.Enable = "off";
            app.StopAutoCalibrationButton.Enable = "on";
        end
        function stopAutoCalibration(app)
            if ~isempty(app.CurrentTask)
                cancel(app.CurrentTask);
                app.CurrentTask=[];
            end
            app.StartAutoCalibrationButton.Enable="on";
            app.StopAutoCalibrationButton.Enable="off";
        end
    end

    methods(Access = private, Static)
        function calibration_etlScan_worker(etlScanner,dataNotificator,resultNotificator)
            arguments(Input)
                etlScanner calibration.ETLScanner
                dataNotificator
                resultNotificator 
            end

            hasNext = true;
            while(hasNext)
                [hasNext,image]=etlScanner.next();
                dataNotificator({etlScanner.getCurrentScores,image});
            end
            resultNotificator(etlScanner.getResult);
        end

        function calibration_testAffineTransform_worker(affineTester,dataNotificator,resultNotificator)
            arguments(Input)
                affineTester calibration.AffineTransformTester
                dataNotificator
                resultNotificator
            end

            [calibrator,image,detectedSpots,expectedSpots] =affineTester.tryByTriangle();
            dataNotificator({calibrator,image,detectedSpots,expectedSpots});
            iteration=4;
            calibrators=[];
            for i=1:iteration
                [c,image,detectedSpots,expectedSpots] = affineTester.tryByTriangleWithProvisionalCalibrator(calibrator);

                if isempty(c)
                    disp("Failed to calculate affine transform parameter, Stopped.");
                    return;
                end

                dataNotificator({c,image,detectedSpots,expectedSpots});
                calibrators=[calibrators,c];
            end
            calibrator = calibration.PositionCalibrator.mean(calibrators);
            resultNotificator(calibrator);
        end

        function calibration_scanWeightMap_worker(wmScanner,dataNotificator,resultNotificator)
            arguments(Input)
                wmScanner calibration.WeightMapScanner
                dataNotificator
                resultNotificator
            end

            hasNext = true;
            while(hasNext)
                [hasNext,image]=wmScanner.next;
                dataNotificator({image});
            end
            resultNotificator(wmScanner.getWeightMap);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, slm, etl, camera, foldername)
            arguments(Input)
                app tools.gui.AutoCalibrator
                slm devices.slm.PhaseSLM
                etl devices.etl.ETL
                camera devices.camera.Camera
                foldername = "./save/calibration/1"
            end
            app.SLM = slm;
            app.ETL = etl;
            app.Camera = camera;
            [ysize,xsize]=app.SLM.getPixelArraySize();
            pixelpitch_um=app.SLM.getPixelPitch();
            app.SLMHeightLabel.Text = string(ysize);
            app.SLMWidthLabel.Text = string(xsize);
            app.SLMPixelPitchLabel.Text = string(pixelpitch_um);
            app.CurrentFolderTextArea.Value = foldername;
            [minValue,maxValue] = etl.getValueRange;
            app.ManualETLValueSlider.Limits = [minValue, maxValue];
            title(app.AutoCalibrationStatusAxes,"")
            xlabel(app.AutoCalibrationStatusAxes,"");
            title(app.AutoETLScanStatusUIAxes,"")
            xlabel(app.AutoETLScanStatusUIAxes,"");
        end

        % Button pushed function: FolderOpenButton
        function FolderOpenButtonPushed(app, event)
            if exist("./save/calibration","dir") ~= 7
                mkdir("./save","calibration");
            end
            path=uigetdir("./save/calibration");
            if path == 0
                return;
            end
            if app.openFolder(path)
                app.CurrentFolderTextArea.Value = path;
            else
                fig=uifigure;
                uialert(fig,"This folder has correct setting information. Please select correct folder.","Invalid Folder")
            end
        end

        % Button pushed function: FolderSelectButton
        function FolderSelectButtonPushed(app, event)
            mkdir("./save","calibration");
            path=uigetdir("./save/calibration");
            if path == 0
                return;
            end
            app.CurrentFolderTextArea.Value = path;
        end

        % Button pushed function: FolderSaveButton
        function FolderSaveButtonPushed(app, event)
            if ~app.saveFolder(app.CurrentFolderTextArea.Value{1})
                fig=uifigure;
                uialert(fig,"Failed to save data to the folder","Write Error")
            end
        end

        % Button pushed function: StartAutoCalibrationButton
        function StartAutoCalibrationButtonPushed(app, event)
            if app.Working
                return;
            end
            app.startAutoCalibration();
            for c = app.CalibrationStatusTree.Children
                delete(c);
            end

            % もう無理 来年度からMATLABは使いません
            imComp=app.AutoCalibrationStatusImage;
            graphComp=app.AutoCalibrationStatusAxes;
            
            app.calibration_etlScan(imComp,graphComp,@() app.calibration_testAffineTransform(imComp,@() app.calibration_scanWeightMap(imComp,[])));
            
        end

        % Button pushed function: StopAutoCalibrationButton
        function StopAutoCalibrationButtonPushed(app, event)
            app.stopAutoCalibration();
        end

        % Button pushed function: AutoScanStartButton
        function AutoScanStartButtonPushed(app, event)
            app.calibration_etlScan(app.AutoETLScanStatusImage,app.AutoETLScanStatusUIAxes,[]);
        end

        % Button pushed function: ManualETLSelectButton
        function ManualETLSelectButtonPushed(app, event)
            app.setETLValue(app.ManualETLValueSlider.Value);
        end

        % Button pushed function: ManualETLApplyButton
        function ManualETLApplyButtonPushed(app, event)
            [ysize,xsize]=app.SLM.getPixelArraySize();
            pixelpitch = app.SLM.getPixelPitch();
            map = devices.slm.PhaseMap(xsize,ysize,pixelpitch,pixelpitch,app.FocalLengthSpinner.Value,app.WaveLengthSpinner.Value);
            map.addSpot(app.FirstCalibSpotXSpinner.Value,app.FirstCalibSpotYSpinner.Value,0);
            app.SLM.apply(map.getPhaseArray);
            pause(0.1);
            image=app.Camera.take();
            app.AutoETLScanStatusImage.ImageSource = app.addMarkerToImage(image,[],[]);
        end

        % Button pushed function: AutoAffineTestStartButton
        function AutoAffineTestStartButtonPushed(app, event)
            if isempty(app.ETLValue)
                return;
            end
            app.calibration_testAffineTransform(app.AutoAffineTestImage,[]);
        end

        % Button down function: CoordinateCorrectionTab
        function CoordinateCorrectionTabButtonDown(app, event)
            if isempty(app.ETLValue)
                app.AutoAffineTestStartButton.Enable = "off";
            else
                app.AutoAffineTestStartButton.Enable = "on";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.AutoTab = uitab(app.TabGroup);
            app.AutoTab.Title = 'Auto';
            app.GridLayout6 = uigridlayout(app.AutoTab);
            app.GridLayout7 = uigridlayout(app.GridLayout6);
            app.StatusPanel = uipanel(app.GridLayout7);
            app.StatusPanel.Title = 'Status';
            app.CalibrationStatusTree = uitree(app.StatusPanel);
            app.GridLayout8 = uigridlayout(app.GridLayout7);
            app.StartAutoCalibrationButton = uibutton(app.GridLayout8, 'push');
            app.StartAutoCalibrationButton.ButtonPushedFcn = createCallbackFcn(app, @StartAutoCalibrationButtonPushed, true);
            app.StartAutoCalibrationButton.Text = 'Start';
            app.StopAutoCalibrationButton = uibutton(app.GridLayout8, 'push');
            app.StopAutoCalibrationButton.ButtonPushedFcn = createCallbackFcn(app, @StopAutoCalibrationButtonPushed, true);
            app.StopAutoCalibrationButton.Enable = 'off';
            app.StopAutoCalibrationButton.Text = 'Stop';
            app.GridLayout10 = uigridlayout(app.GridLayout6);
            app.AutoCalibrationStatusAxes = uiaxes(app.GridLayout10);
            xlabel(app.AutoCalibrationStatusAxes, 'X')
            ylabel(app.AutoCalibrationStatusAxes, 'Y')
            zlabel(app.AutoCalibrationStatusAxes, 'Z')
            app.AutoCalibrationStatusImage = uiimage(app.GridLayout10);
            app.ETLScanTab = uitab(app.TabGroup);
            app.ETLScanTab.Title = 'ETL Scan';
            app.GridLayout11 = uigridlayout(app.ETLScanTab);
            app.AutoETLScanStatusUIAxes = uiaxes(app.GridLayout11);
            title(app.AutoETLScanStatusUIAxes, 'Title')
            xlabel(app.AutoETLScanStatusUIAxes, 'X')
            ylabel(app.AutoETLScanStatusUIAxes, 'Y')
            zlabel(app.AutoETLScanStatusUIAxes, 'Z')
            app.AutoETLScanStatusImage = uiimage(app.GridLayout11);
            app.ManualSelectionPanel = uipanel(app.GridLayout11);
            app.ManualSelectionPanel.Title = 'Manual Selection';
            app.GridLayout12 = uigridlayout(app.ManualSelectionPanel);
            app.ManualETLValueSlider = uislider(app.GridLayout12);
            app.ManualETLApplyButton = uibutton(app.GridLayout12, 'push');
            app.ManualETLApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ManualETLApplyButtonPushed, true);
            app.ManualETLApplyButton.Text = 'Apply';
            app.ManualETLSelectButton = uibutton(app.GridLayout12, 'push');
            app.ManualETLSelectButton.ButtonPushedFcn = createCallbackFcn(app, @ManualETLSelectButtonPushed, true);
            app.ManualETLSelectButton.Text = 'Select';
            app.AutoScanPanel = uipanel(app.GridLayout11);
            app.AutoScanPanel.Title = 'Auto Scan';
            app.AutoScanStartButton = uibutton(app.AutoScanPanel, 'push');
            app.AutoScanStartButton.ButtonPushedFcn = createCallbackFcn(app, @AutoScanStartButtonPushed, true);
            app.AutoScanStartButton.Text = 'Start';
            app.CoordinateCorrectionTab = uitab(app.TabGroup);
            app.CoordinateCorrectionTab.Title = 'Coordinate Correction';
            app.CoordinateCorrectionTab.ButtonDownFcn = createCallbackFcn(app, @CoordinateCorrectionTabButtonDown, true);
            app.GridLayout13 = uigridlayout(app.CoordinateCorrectionTab);
            app.AutoAffineTestImage = uiimage(app.GridLayout13);
            app.AutoAffineTestLabel = uilabel(app.GridLayout13);
            app.AutoAffineTestLabel.Text = '';
            app.GridLayout15 = uigridlayout(app.GridLayout13);
            app.ManualPanel = uipanel(app.GridLayout15);
            app.ManualPanel.Title = 'Manual';
            app.GridLayout16 = uigridlayout(app.ManualPanel);
            app.XP1EditFieldLabel = uilabel(app.GridLayout16);
            app.XP1EditFieldLabel.Text = 'X(P1)';
            app.XP1EditField = uieditfield(app.GridLayout16, 'numeric');
            app.YP1EditFieldLabel = uilabel(app.GridLayout16);
            app.YP1EditFieldLabel.Text = 'Y(P1)';
            app.YP1EditField = uieditfield(app.GridLayout16, 'numeric');
            app.XP2EditFieldLabel = uilabel(app.GridLayout16);
            app.XP2EditFieldLabel.Text = 'X(P2)';
            app.XP2EditField = uieditfield(app.GridLayout16, 'numeric');
            app.YP2EditFieldLabel = uilabel(app.GridLayout16);
            app.YP2EditFieldLabel.Text = 'Y(P2)';
            app.YP2EditField = uieditfield(app.GridLayout16, 'numeric');
            app.XP3EditFieldLabel = uilabel(app.GridLayout16);
            app.XP3EditFieldLabel.Text = 'X(P3)';
            app.XP3EditField = uieditfield(app.GridLayout16, 'numeric');
            app.YP3EditFieldLabel = uilabel(app.GridLayout16);
            app.YP3EditFieldLabel.Text = 'Y(P3)';
            app.YP3EditField = uieditfield(app.GridLayout16, 'numeric');
            app.ManualAffineTestButton = uibutton(app.GridLayout16, 'push');
            app.ManualAffineTestButton.Text = 'Test';
            app.AutoTestPanel = uipanel(app.GridLayout13);
            app.AutoTestPanel.Title = 'Auto Test';
            app.AutoAffineTestStartButton = uibutton(app.AutoTestPanel, 'push');
            app.AutoAffineTestStartButton.ButtonPushedFcn = createCallbackFcn(app, @AutoAffineTestStartButtonPushed, true);
            app.AutoAffineTestStartButton.Text = 'Start';
            app.WeightMapTab = uitab(app.TabGroup);
            app.WeightMapTab.Title = 'Weight Map';
            app.ParametersPanel = uipanel(app.GridLayout);
            app.ParametersPanel.Title = 'Parameters';
            app.GridLayout3 = uigridlayout(app.ParametersPanel);
            app.LaserPanel = uipanel(app.GridLayout3);
            app.LaserPanel.Title = 'Laser';
            app.GridLayout4 = uigridlayout(app.LaserPanel);
            app.WaveLengthnmLabel = uilabel(app.GridLayout4);
            app.WaveLengthnmLabel.Text = {'Wave Length'; '(nm)'};
            app.WaveLengthSpinner = uispinner(app.GridLayout4);
            app.WaveLengthSpinner.Step = 0.5;
            app.WaveLengthSpinner.Limits = [1 Inf];
            app.WaveLengthSpinner.Value = 920;
            app.OpticalSystemPanel = uipanel(app.GridLayout3);
            app.OpticalSystemPanel.Title = 'Optical System';
            app.GridLayout4_2 = uigridlayout(app.OpticalSystemPanel);
            app.FocalLengthoflensmLabel = uilabel(app.GridLayout4_2);
            app.FocalLengthoflensmLabel.Text = {'Focal Length'; 'of lens'; '(μm)'};
            app.FocalLengthSpinner = uispinner(app.GridLayout4_2);
            app.FocalLengthSpinner.Step = 0.5;
            app.FocalLengthSpinner.Limits = [1 Inf];
            app.FocalLengthSpinner.Value = 8000;
            app.SLMPanel = uipanel(app.GridLayout3);
            app.SLMPanel.Title = 'SLM';
            app.GridLayout5 = uigridlayout(app.SLMPanel);
            app.PixelPitchmLabel = uilabel(app.GridLayout5);
            app.PixelPitchmLabel.Text = 'Pixel Pitch (μm)';
            app.PixelWidthLabel = uilabel(app.GridLayout5);
            app.PixelWidthLabel.Text = 'Pixel Width';
            app.PixelHeightLabel = uilabel(app.GridLayout5);
            app.PixelHeightLabel.Text = 'Pixel Height';
            app.SLMPixelPitchLabel = uilabel(app.GridLayout5);
            app.SLMPixelPitchLabel.Text = '0';
            app.SLMWidthLabel = uilabel(app.GridLayout5);
            app.SLMWidthLabel.Text = '0';
            app.SLMHeightLabel = uilabel(app.GridLayout5);
            app.SLMHeightLabel.Text = '0';
            app.SavedFolderPanel = uipanel(app.GridLayout3);
            app.SavedFolderPanel.Title = 'Saved Folder';
            app.GridLayout5_2 = uigridlayout(app.SavedFolderPanel);
            app.FolderOpenButton = uibutton(app.GridLayout5_2, 'push');
            app.FolderOpenButton.ButtonPushedFcn = createCallbackFcn(app, @FolderOpenButtonPushed, true);
            app.FolderOpenButton.Text = 'Open';
            app.FolderSaveButton = uibutton(app.GridLayout5_2, 'push');
            app.FolderSaveButton.ButtonPushedFcn = createCallbackFcn(app, @FolderSaveButtonPushed, true);
            app.FolderSaveButton.Text = 'Save';
            app.CurrentFolderTextArea = uitextarea(app.GridLayout5_2);
            app.FolderSelectButton = uibutton(app.GridLayout5_2, 'push');
            app.FolderSelectButton.ButtonPushedFcn = createCallbackFcn(app, @FolderSelectButtonPushed, true);
            app.FolderSelectButton.Text = 'Select';
            app.CalibrationPatternPanel = uipanel(app.GridLayout3);
            app.CalibrationPatternPanel.Title = 'Calibration Pattern';
            app.GridLayout9 = uigridlayout(app.CalibrationPatternPanel);
            app.FirstSpotXmLabel = uilabel(app.GridLayout9);
            app.FirstSpotXmLabel.Text = 'First Spot X (μm)';
            app.FirstSpotYmLabel = uilabel(app.GridLayout9);
            app.FirstSpotYmLabel.Text = 'First Spot Y (μm)';
            app.PatternScaleLabel = uilabel(app.GridLayout9);
            app.PatternScaleLabel.Text = 'Pattern Scale';
            app.FirstCalibSpotXSpinner = uispinner(app.GridLayout9);
            app.FirstCalibSpotXSpinner.Value = -50;
            app.FirstCalibSpotYSpinner = uispinner(app.GridLayout9);
            app.FirstCalibSpotYSpinner.Value = -50;
            app.CalibPatternScaleSpinner = uispinner(app.GridLayout9);
            app.CalibPatternScaleSpinner.Limits = [0 1000];
            app.CalibPatternScaleSpinner.Value = 15;
            app.CalibrationParametersPanel = uipanel(app.GridLayout);
            app.CalibrationParametersPanel.Title = 'Calibration Parameters';
            app.GridLayout2 = uigridlayout(app.CalibrationParametersPanel);
            app.ETLCurrentRawValuePanel = uipanel(app.GridLayout2);
            app.ETLCurrentRawValuePanel.Title = 'ETL Current (Raw Value)';
            app.ETLCurrentLabel = uilabel(app.ETLCurrentRawValuePanel);
            app.ETLCurrentLabel.Text = 'None';
            app.CoordinateTransformPanel = uipanel(app.GridLayout2);
            app.CoordinateTransformPanel.Title = 'Coordinate Transform';
            app.TransformParamLabel = uilabel(app.CoordinateTransformPanel);
            app.TransformParamLabel.Text = 'None';
            app.WeightMapPanel = uipanel(app.GridLayout2);
            app.WeightMapPanel.Title = 'Weight Map';
            app.WeightMapImage = uiimage(app.WeightMapPanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AutoCalibrator(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end